classdef vector_field_input_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RangeEditField                matlab.ui.control.NumericEditField
        RangeEditFieldLabel           matlab.ui.control.Label
        QuickStartGuideTextArea       matlab.ui.control.TextArea
        QuickStartGuideTextAreaLabel  matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        UpperEditField                matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        LowerEditField                matlab.ui.control.NumericEditField
        LowerEditFieldLabel           matlab.ui.control.Label
        ZcomponentEditField           matlab.ui.control.EditField
        ZcomponentEditFieldLabel      matlab.ui.control.Label
        PlotVectorFieldButton         matlab.ui.control.Button
        YcomponentEditField           matlab.ui.control.EditField
        YcomponentEditFieldLabel      matlab.ui.control.Label
        XcomponentEditField           matlab.ui.control.EditField
        XcomponentEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotVectorFieldButton
        function PlotVectorFieldButtonPushed(app, event)
            % Note: Will add some function calling for comprehension and clean
            % Note: Add the properties of divergence, curl, and conservative vector fields in text box
            
            % Clear the axes before plotting
            cla(app.UIAxes,'reset')

            % Read lower, range, and upper bound
            lower_bound = app.LowerEditField.Value;
            upper_bound = app.UpperEditField.Value;
            range = app.RangeEditField.Value;
            
            % Create the grid
            [matrix_x ,matrix_y, matrix_z] = meshgrid(lower_bound:range:upper_bound);
            
            % Read and Convert user's input from char to string in the text field 
            x_expr = convertCharsToStrings(app.XcomponentEditField.Value);
            y_expr = convertCharsToStrings(app.YcomponentEditField.Value);
            z_expr = convertCharsToStrings(app.ZcomponentEditField.Value);
            
            % Create zeros 3D array to store the results
            [rows, cols, heights] = size(matrix_x);
            matrix_x_output = zeros(rows, cols, heights);
            matrix_y_output = zeros(rows, cols, heights);
            matrix_z_output = zeros(rows, cols, heights);
            
            % Loop through each element in the orignal array
            % Update the 3D placeholder matrix correspondingly
            for i = 1:rows
                for j = 1:cols
                    for k = 1:heights
                        % Set x, y, z = to the corresponding element to use eval
                        x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
    
                        % Use eval on the x, y and z element above to
                        % fill up the placeholder 3D array
                        if (x_expr ~= "")
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end

                        if (y_expr ~= "")
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end

                        if (z_expr ~= "")
                           matrix_z_output(i,j,k) = eval(z_expr);
                        end

                    end 
                end
            end
            
            % Color stuff
            if (app.ColorDropDown.Value == "red")
                color = "r";
            elseif (app.ColorDropDown.Value == "blue")
                color = "b";
            elseif (app.ColorDropDown.Value == "green")
                color = "g";
            elseif (app.ColorDropDown.Value == "cyan")
                color = "c";
            elseif (app.ColorDropDown.Value == "purple")
                color = "m";
            elseif (app.ColorDropDown.Value == "yellow")
                color = "y";
            elseif (app.ColorDropDown.Value == "black")
                color = "k";
            end

            % Plot 2D and 3D
            if (z_expr ~= "")
               quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
                   matrix_x_output, matrix_y_output, matrix_z_output, 1, color)
            else
                quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 1, color)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector Field Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.XcomponentEditFieldLabel.Text = 'X-component';
            app.XcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.YcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.YcomponentEditFieldLabel.Text = 'Y-component';
            app.YcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.PlotVectorFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotVectorFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVectorFieldButtonPushed, true);
            app.PlotVectorFieldButton.Text = 'Plot Vector Field';
            app.ZcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.ZcomponentEditFieldLabel.Text = 'Z-component';
            app.ZcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerEditField.Value = -3;
            app.UpperEditFieldLabel = uilabel(app.UIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperEditField.Value = 3;
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'blue', 'green', 'cyan', 'purple', 'yellow', 'black'};
            app.ColorDropDown.Value = 'red';
            app.QuickStartGuideTextAreaLabel = uilabel(app.UIFigure);
            app.QuickStartGuideTextAreaLabel.Text = 'Quick Start Guide';
            app.QuickStartGuideTextArea = uitextarea(app.UIFigure);
            app.QuickStartGuideTextArea.Value = {'1. Use * or / for multiplication, like 2*x or 2/x.'; ''; '2. Adjust bounds numerically for different views.'; '(lower bound should be smaller than upper bound)'; ''; '3. Use x, y, and z as variables. (will update another variable expression soon)'; ''; '4. Drag the Vector Field Plot to change viewing perspective.'; ''; '5. For 2D vectors, omit Z-component (r = <x, y>).'; ''; '6. When plotting some Division Expression as X/Y, adjust the bounds to some irrational numbers '; '(from -2.789 to 2.789)'; 'to avoid dividing by zeros  as the vector field plot will not show anything (will fix soon).'; ''; 'Check for more information, guides, demos, and updates at: https://github.com/Ai4Math/MATLAB-GUI'; ''};
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vector_field_input_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end